classdef coachAppV2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        FileMenu                 matlab.ui.container.Menu
        NewMenu                  matlab.ui.container.Menu
        OpenMenu                 matlab.ui.container.Menu
        SaveMenu                 matlab.ui.container.Menu
        HelpMenu                 matlab.ui.container.Menu
        InstructionsMenu         matlab.ui.container.Menu
        ContactMenu              matlab.ui.container.Menu
        GridLayout               matlab.ui.container.GridLayout
        LeftPanel                matlab.ui.container.Panel
        SettingsPanel            matlab.ui.container.Panel
        UnexportedLabel          matlab.ui.control.Label
        ExportButton             matlab.ui.control.Button
        BranchDropDown           matlab.ui.control.DropDown
        BranchDropDownLabel      matlab.ui.control.Label
        NotesEditField           matlab.ui.control.EditField
        NotesEditFieldLabel      matlab.ui.control.Label
        MeetDateDatePicker       matlab.ui.control.DatePicker
        MeetDateDatePickerLabel  matlab.ui.control.Label
        CoachNameEditField       matlab.ui.control.EditField
        CoachNameEditFieldLabel  matlab.ui.control.Label
        MeetNameEditField        matlab.ui.control.EditField
        MeetNameEditFieldLabel   matlab.ui.control.Label
        NofileselectedLabel      matlab.ui.control.Label
        SelectRosterFileButton   matlab.ui.control.Button
        RightPanel               matlab.ui.container.Panel
        UITable                  matlab.ui.control.Table
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = private)
        roster % Description
        uiTab
    end
    
    methods (Access = private)
        
        function [] = updateTable(app)
            inputTable = readtable(app.roster);
            outputTable = inputTable(:,["Participant","Age","Gender"]);

            numSwimmers = size(outputTable,1);
            logicMatrix = false(numSwimmers,5);
            strokes = ["Freestyle" "Breaststroke" "IM" "Backstroke" "Butterfly"];
            logicTable = array2table(logicMatrix,"VariableNames",strokes);

            relayMatrix = cell(numSwimmers,2);
            relays = ["Medley Relay" "Free Relay"];
            relayTable = cell2table(relayMatrix, "VariableNames",relays);

            outputTable = [outputTable logicTable relayTable];
            %app.uiTab = uitable(app.RightPanel,"Data",outputTable,"Position",[6 6 838 469]);
            app.UITable.Data = outputTable;
        end

        function exportTable = formatData(app)
            name = app.UITable.Data{:,1};
            lastFirst = split(name,', ');
            firstNames = lastFirst(:,2);
            lastNames = lastFirst(:,1);

            age = app.UITable.Data{:,2};
            gender = app.UITable.Data{:,3};
            team = app.BranchDropDown.Value;

            
            dynamic = cell(size(lastNames,1),3);
            for row = 1:size(lastNames,1)
                static = {"free", "breast", "im", "back", "fly"};
                logic = app.UITable.Data{row,["Freestyle","Breaststroke","IM","Backstroke","Butterfly"]};
                events = static(logic);
                while size(events,2) < 3
                    events = [events ''];
                end
                if size(events,2) > 3
                    events = events(1:3);
                end
                dynamic(row,:) = events;
                disp(events)
            end
            disp(dynamic)
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: SelectRosterFileButton
        function SelectRosterFileButtonPushed(app, event)
            [rosterFile, rosterLoc] = uigetfile(".csv");
            rosterPath = sprintf("%s%s",rosterLoc,rosterFile);
            app.NofileselectedLabel.Text = sprintf("Loaded: %s",rosterFile);
            app.roster = rosterPath;

            app.updateTable()
        end

        % Button pushed function: ExportButton
        function ExportButtonPushed(app, event)
            app.formatData()
            %writetable(app.UITable.Data,"outputFile.csv")
            %[userFile, saveLocation] = uiputfile("*.csv","Export entries","outputFile.csv");
            %app.UnexportedLabel.Text = sprintf("%s saved to %s",userFile,saveLocation);
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {481, 481};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {263, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.FileMenu = uimenu(app.UIFigure);
            app.FileMenu.Text = 'File';
            app.NewMenu = uimenu(app.FileMenu);
            app.NewMenu.Text = 'New';
            app.OpenMenu = uimenu(app.FileMenu);
            app.OpenMenu.Text = 'Open';
            app.SaveMenu = uimenu(app.FileMenu);
            app.SaveMenu.Text = 'Save';
            app.HelpMenu = uimenu(app.UIFigure);
            app.HelpMenu.Text = 'Help';
            app.InstructionsMenu = uimenu(app.HelpMenu);
            app.InstructionsMenu.Text = 'Instructions';
            app.ContactMenu = uimenu(app.HelpMenu);
            app.ContactMenu.Text = 'Contact';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.SettingsPanel = uipanel(app.LeftPanel);
            app.SettingsPanel.Title = 'Settings';
            app.SelectRosterFileButton = uibutton(app.SettingsPanel, 'push');
            app.SelectRosterFileButton.ButtonPushedFcn = createCallbackFcn(app, @SelectRosterFileButtonPushed, true);
            app.SelectRosterFileButton.Text = 'Select Roster File';
            app.NofileselectedLabel = uilabel(app.SettingsPanel);
            app.NofileselectedLabel.Text = 'No file selected';
            app.MeetNameEditFieldLabel = uilabel(app.SettingsPanel);
            app.MeetNameEditFieldLabel.Text = 'Meet Name';
            app.MeetNameEditField = uieditfield(app.SettingsPanel, 'text');
            app.CoachNameEditFieldLabel = uilabel(app.SettingsPanel);
            app.CoachNameEditFieldLabel.Text = 'Coach Name';
            app.CoachNameEditField = uieditfield(app.SettingsPanel, 'text');
            app.MeetDateDatePickerLabel = uilabel(app.SettingsPanel);
            app.MeetDateDatePickerLabel.Text = 'Meet Date';
            app.MeetDateDatePicker = uidatepicker(app.SettingsPanel);
            app.NotesEditFieldLabel = uilabel(app.SettingsPanel);
            app.NotesEditFieldLabel.Text = 'Notes';
            app.NotesEditField = uieditfield(app.SettingsPanel, 'text');
            app.BranchDropDownLabel = uilabel(app.SettingsPanel);
            app.BranchDropDownLabel.Text = 'Branch';
            app.BranchDropDown = uidropdown(app.SettingsPanel);
            app.BranchDropDown.Items = {'Effingham', 'Habersham', 'Islands', 'Liberty', 'West Chatham'};
            app.BranchDropDown.Placeholder = 'Select';
            app.BranchDropDown.Value = 'Effingham';
            app.ExportButton = uibutton(app.SettingsPanel, 'push');
            app.ExportButton.ButtonPushedFcn = createCallbackFcn(app, @ExportButtonPushed, true);
            app.ExportButton.Text = 'Export';
            app.UnexportedLabel = uilabel(app.SettingsPanel);
            app.UnexportedLabel.Text = 'Unexported';
            app.RightPanel = uipanel(app.GridLayout);
            app.UITable = uitable(app.RightPanel);
            app.UITable.ColumnName = {'Name'; 'Age'; 'Gender'; 'Freestyle'; 'Breaststroke'; 'IM'; 'Backstroke'; 'Butterfly'; 'Medley Relay'; 'Freestyle Relay'};
            app.UITable.RowName = {};
            app.UITable.ColumnSortable = [true true true false false false false false false false];
            app.UITable.ColumnEditable = [false false false true true true true true true true];
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = coachAppV2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end